classdef HistogramPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp % Description
        xData
        yData
        amplitude1
        mean1
        stdev1
        amplitude2
        mean2
        stdev2
    end
    
    methods (Access = private)
        
        function twoPeakFit(app, bins, binCounts, amplitude1, mean1, stdev1, amplitude2, mean2, stdev2)            
            
            oldBinCounts = binCounts; 
            binCounts = [binCounts, 0]; 
            [app.xData, app.yData] = prepareCurveData( bins, binCounts );
            ft = fittype( 'A1 * exp(-((x - mu1)/(2 * sigma1))^2) + A2 * exp(-((x - mu2)/(2 * sigma2))^2)', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Levenberg-Marquardt';
            opts.Display = 'Off';
            opts.StartPoint = [amplitude1 amplitude2 mean1 mean2 stdev1 stdev2]; 
            [fitresult, ~] = fit( app.xData, app.yData, ft, opts );
            
            app.amplitude1 = fitresult.A1; 
            app.amplitude2 = fitresult.A2; 
            app.mean1 = fitresult.mu1; 
            app.mean2 = fitresult.mu2; 
            app.stdev1 = fitresult.sigma1; 
            app.stdev2 = fitresult.sigma2; 
            
        %% Fit: 'untitled fit 1'.


% Set up fittype and options.


% Fit model to data.


% Plot fit with data.
%             h = histogram(app.UIAxes, 'BinEdges', bins, 'BinCounts', oldBinCounts); 
%             % figure( 'Name', 'untitled fit 1' );
%             hold(app.UIAxes); 
%             plot( fitresult, app.xData, app.yData );
%             legend( h, 'binCounts vs. bins', 'untitled fit 1', 'Location', 'NorthEast', 'Interpreter', 'none' );
%             % Label axes
%             xlabel( 'bins', 'Interpreter', 'none' );
%             ylabel( 'binCounts', 'Interpreter', 'none' );
%             grid on
%             hold off; 
        end
        
        function [amplitude1, mean1, amplitude2, mean2] = twoMaxFinder(app, bins, binCounts)
            localMaxima = islocalmax(binCounts); 
            localMaximaAmplitudes = binCounts(localMaxima);  
            amplitude1 = 0; 
            amplitude2 = 0;
            mean1 = 0; 
            mean2 = 0;
            mean1_index = 0; 
            for i = 1:length(localMaximaAmplitudes) 
                if localMaximaAmplitudes(i) > amplitude2 
                    if localMaximaAmplitudes(i) > amplitude1 
                        amplitude2 = amplitude1; 
                        mean2 = mean1; 
                        amplitude1 = localMaximaAmplitudes(i); 
                        mean1 = find(binCounts==localMaximaAmplitudes(i)); % figure out which of the bins corresponds to the global maximum
                        mean1_index = min(mean1(:)); 
                        mean1 = bins(mean1_index); % take the least element of whatever list is generated by that procedure 
                        
                    else 
                        amplitude2 = localMaximaAmplitudes(i); 
                        mean2 = find(binCounts==localMaximaAmplitudes(i));   
                        mean2 = bins(min(mean2(mean2 > mean1_index))); 
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, InputParameters, BinEdges, BinCounts, MaxInten, BinWidth, PeakFitChoice)
            app.callingapp = InputParameters;  
            x = 1:0.01:max(BinEdges); 
            if PeakFitChoice == 2
                [app.amplitude1, app.mean1, app.amplitude2, app.mean2] = twoMaxFinder(app, BinEdges, BinCounts); 
                twoPeakFit(app, BinEdges, BinCounts, app.amplitude1, app.mean1, 50, app.amplitude2, app.mean2, 50); 
                fitfunction = @ (x, A1, mu1, sigma1, A2, mu2, sigma2) A1 * exp(-((x - mu1)/(2 * sigma1)).^2) + A2 * exp(-((x - mu2)/(2 * sigma2)).^2); 
                fitresult = fitfunction(x, app.amplitude1, app.mean1, app.stdev1, app.amplitude2, app.mean2, app.stdev2); 
            end
            
            % f = figure(); 
            % ax = gca; 
            histogram(app.UIAxes, 'BinEdges', BinEdges, 'BinCounts', BinCounts)
            hold(app.UIAxes, 'on') 
            plot(x, fitresult, app.xData, app.yData)
            
            % hold(app.UIAxes);
            
            % plot( fitresult, app.xData, app.yData );
            
%             hold(app.UIAxes); 
%             
%             ft = fittype( '1/(sigma * sqrt(2 * pi)) * exp(-((x - mu)/(2 * sigma))^2)', 'independent', 'x', 'dependent', 'y' );
%             opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
%             opts.Algorithm = 'Levenberg-Marquardt';
%             opts.Display = 'Off';
%             opts.StartPoint
%             fit 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spot Intensity versus Frequency')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistogramPlot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end