classdef InputParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThreeDAppUIFigure             matlab.ui.Figure
        FileNameEditFieldLabel        matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FindFileButton                matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        BinWidthEditFieldLabel        matlab.ui.control.Label
        BinWidthEditField             matlab.ui.control.NumericEditField
        X0_1EditFieldLabel            matlab.ui.control.Label
        X0_1EditField                 matlab.ui.control.NumericEditField
        ScalingFactorUsedEditFieldLabel  matlab.ui.control.Label
        ScalingFactorUsedEditField    matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        HeightEditField_2Label        matlab.ui.control.Label
        HeightEditField_2             matlab.ui.control.NumericEditField
        EnterpixelvoxeldimensionsinnanometersLabel  matlab.ui.control.Label
        CalculateConcentrationButton  matlab.ui.control.Button
        NumberOfMoleculesEditFieldLabel  matlab.ui.control.Label
        NumberOfMoleculesEditField    matlab.ui.control.NumericEditField
        MolarConcentrationEditFieldLabel  matlab.ui.control.Label
        MolarConcentrationEditField   matlab.ui.control.NumericEditField
        Orfor3DimagesprovideoverallvolumeandunitsLabel  matlab.ui.control.Label
        VolumeEditFieldLabel          matlab.ui.control.Label
        VolumeEditField               matlab.ui.control.NumericEditField
        DropDown                      matlab.ui.control.DropDown
        X0_2EditFieldLabel            matlab.ui.control.Label
        X0_2EditField                 matlab.ui.control.NumericEditField
        NumberOfPeaksButtonGroup      matlab.ui.container.ButtonGroup
        OnepeakButton                 matlab.ui.control.RadioButton
        TwopeaksButton                matlab.ui.control.RadioButton
        For2DimagesprovideoverallareaandunitsLabel  matlab.ui.control.Label
        DropDown_2                    matlab.ui.control.DropDown
        AreaEditFieldLabel            matlab.ui.control.Label
        AreaEditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        FileName
        HistogramPlot % Calls histogram plotting app 
        BinWidth
        ScalingFactor
        ChosenX0Value
        Scaled
        xLocation
        yLocation
        zLocation
        IntensityLocation
        DeltaXVoxels
        DeltaYVoxels
        DeltaZVoxels
        Bins 
        BinCounts 
        NonZeroMolecules
        NumSpots
        NumMolecules
        NumPeaks
        VoxelLength
        VoxelWidth
        VoxelHeight
        VolumeL
        Concentration
        CellularVolume
        CellularVolumeUnit
        xData
        yData
        zData
        amplitude1   
        amplitude2  
        X01
        X02
        stdev1  
        stdev2  
    end
    
    methods (Access = private)
        
        function [amplitude1, mean1, amplitude2, mean2] = twoMaxFinder(app, bins, binCounts)
            localMaxima = islocalmax(binCounts); 
            localMaximaAmplitudes = binCounts(localMaxima);  
            amplitude1 = 0; 
            amplitude2 = 0;
            mean1 = 0; 
            mean2 = 0;
            mean1_index = 0; 
            for i = 1:length(localMaximaAmplitudes) 
                if localMaximaAmplitudes(i) > amplitude2 
                    if localMaximaAmplitudes(i) > amplitude1 
                        amplitude2 = amplitude1; 
                        mean2 = mean1; 
                        amplitude1 = localMaximaAmplitudes(i); 
                        mean1 = find(binCounts==localMaximaAmplitudes(i)); % figure out which of the bins corresponds to the global maximum
                        mean1_index = min(mean1(:)); 
                        mean1 = bins(mean1_index); % take the least element of whatever list is generated by that procedure 
                        
                    else 
                        amplitude2 = localMaximaAmplitudes(i); 
                        mean2 = find(binCounts==localMaximaAmplitudes(i));   
                        mean2 = bins(min(mean2(mean2 > mean1_index))); 
                    end
                end
            end
        end
    
        function twoPeakFit(app, bins, binCounts, amplitude1, mean1, stdev1, amplitude2, mean2, stdev2)            
             
            binCounts = [binCounts, 0]; 
            [app.xData, app.yData] = prepareCurveData( bins, binCounts );
            ft = fittype( 'A1 * exp(-((x - mu1)/(2 * sigma1))^2) + A2 * exp(-((x - mu2)/(2 * sigma2))^2)', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Levenberg-Marquardt';
            opts.Display = 'Off';
            opts.StartPoint = [amplitude1 amplitude2 mean1 mean2 stdev1 stdev2]; 
            [fitresult, ~] = fit( app.xData, app.yData, ft, opts );
            
            app.amplitude1 = fitresult.A1; 
            app.amplitude2 = fitresult.A2; 
            app.X01.Value = fitresult.mu1; 
            app.X02.Value = fitresult.mu2; 
            app.stdev1.Value = fitresult.sigma1; 
            app.stdev2.Value = fitresult.sigma2; 
    
            app.X0_1EditField.Value = app.X01.Value; 
            app.X0_2EditField.Value = app.X02.Value; 
    
        end
        
        function onePeakFit(app, bins, binCounts, amplitude1, mean1, stdev1)
            
            binCounts = [binCounts, 0]; 
            [app.xData, app.yData] = prepareCurveData( bins, binCounts );
            ft = fittype( 'A1 * exp(-((x - mu1)/(2 * sigma1))^2)', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Levenberg-Marquardt';
            opts.Display = 'Off';
            opts.StartPoint = [amplitude1 mean1 stdev1]; 
            [fitresult, ~] = fit( app.xData, app.yData, ft, opts );
            
            app.amplitude1 = fitresult.A1;  
            app.X01.Value = fitresult.mu1; 
            app.stdev1.Value = fitresult.sigma1; 
            
            app.X0_1EditField.Value = app.X01.Value;
            app.X0_2EditField.Value = 0; 
            app.X02.Value = 0; 
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ThreeDAppUIFigure.Name = "Main App"; 
        end

        % Button pushed function: FindFileButton
        function FindFileButtonPushed(app, event)
            [file, path] = uigetfile('*.loc4', 'Select File'); 
            app.FileNameEditField.Value = fullfile(path, file); 
            app.FileName.Value = app.FileNameEditField.Value;
            
            app.ScalingFactor.Value = 150; 
            [scaled, x_data, y_data, z_data, intensity_data, scaling_factor_used, delta_x_voxels, ...
            delta_y_voxels, delta_z_voxels] = ... 
            loc_file_reader3D(app.FileName.Value, 'default', app.ScalingFactor.Value);
            
            app.Scaled = scaled; 
            app.xLocation = x_data; 
            app.yLocation = y_data; 
            app.zLocation = z_data; 
            app.IntensityLocation = intensity_data; 
            app.ScalingFactor.Value = scaling_factor_used; 
            app.DeltaXVoxels.Value = delta_x_voxels; 
            app.DeltaYVoxels.Value = delta_y_voxels; 
            app.DeltaZVoxels.Value = delta_z_voxels; 
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            [app.Bins, app.BinCounts] = binning(app.Scaled, app.BinWidth.Value); 
            
            % max_count = max(app.BinCounts); 
            
%             histogram(app.UIAxes, 'BinEdges', Bins, 'BinCounts', BinCounts)
%             
%             hold on
%             [muHat, sigmaHat] = normfit(app.BinCounts);
%             normal_fit = normpdf(app.Bins, muHat, sigmaHat);
%             plot(app.UIAxes, app.Bins, normal_fit) 
            
            % app.BinsTruncated = [0, bins(bin_counts >= 0.15 * max_count)]; % added 0 because you need 1 more bin edge to enclose all bin_counts data
            % app.BinCountsTruncated = bin_counts(bin_counts >= 0.15 * max_count); 
            
            h = findall(0, 'Type', 'figure'); 
            if length(h) >= 2
                for i = 1:length(h) 
                    if ~strcmp(h(i).Name, app.ThreeDAppUIFigure.Name)
                       close(h(i))
                    end
                end
            end
            
            x = 1:0.01:max(app.Bins); 
            if (app.NumPeaks.Value == 2) && (app.X02.Value > app.X01.Value)
                [app.amplitude1, app.X01.Value, app.amplitude2, app.X02.Value] = twoMaxFinder(app, app.Bins, app.BinCounts); 
                twoPeakFit(app, app.Bins, app.BinCounts, app.amplitude1, app.X01.Value, 50, app.amplitude2, app.X02.Value, 50); 
                fitfunction = @ (x, A1, mu1, sigma1, A2, mu2, sigma2) A1 * exp(-((x - mu1)/(2 * sigma1)).^2) + A2 * exp(-((x - mu2)/(2 * sigma2)).^2); 
                fitresult = fitfunction(x, app.amplitude1, app.X01.Value, app.stdev1.Value, app.amplitude2, app.X02.Value, app.stdev2.Value); 
            else
                app.amplitude1 = max(app.BinCounts(:)); 
                app.X01.Value = find(app.BinCounts == app.amplitude1); 
                onePeakFit(app, app.Bins, app.BinCounts, app.amplitude1, app.X01.Value, app.stdev1.Value)
                fitfunction = @ (x, A1, mu1, sigma1) A1 * exp(-((x - mu1)/(2 * sigma1)).^2); 
                fitresult = fitfunction(x, app.amplitude1, app.X01.Value, app.stdev1.Value); 
            end
            
            f = figure(); 
            ax = gca; 
            histogram(ax, 'BinEdges', app.Bins, 'BinCounts', app.BinCounts)
            hold(ax, 'on') 
            plot(x, fitresult, 'LineWidth', 3)
            xlabel(ax, "Fluorescence Intensity")
            ylabel(ax, "Frequency")
            legend(ax, "Frequency of bin", "Best fit Gaussian")
            title(ax, "Frequency of spot intensities")
            hold(ax, 'off')
            
            
            % app.HistogramPlot = histogram(app.UIAxes, 'BinEdges', app.Bins, 'BinCounts', app.BinCounts)
            
            % histfit(app.BinCounts, length(app.Bins)); 
            % impixelinfo; 
            
            % app.HistogramPlot = HistogramPlot(app, app.Bins, app.BinCounts, max(app.Bins(app.BinCounts > 0)), app.BinWidth.Value, app.NumPeaks.Value); 


            % had to set this here instead of giving ChosenX0Value its own 
            % callback because I want the user to be able to choose the 
            % appropriate bar on the histogram and convert that to the
            % correct x0 value, but I couldn't figure out how to do that
            % before actually generating the histogram, which only comes
            % after the plot button is pushed
                                                                        
            [app.NonZeroMolecules, app.NumSpots.Value, app.NumMolecules.Value] = spot_quantification(app.X01.Value, app.Scaled); 
            app.NumberOfMoleculesEditField.Value = app.NumMolecules.Value; 
            
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.FileName = app.FileNameEditField.Value;
            
        end

        % Value changed function: BinWidthEditField
        function BinWidthEditFieldValueChanged(app, event)
            app.BinWidth.Value = app.BinWidthEditField.Value;
            
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            app.VoxelLength.Value = app.LengthEditField.Value;
            
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.VoxelWidth.Value = app.WidthEditField.Value;
            
        end

        % Value changed function: HeightEditField_2
        function HeightEditField_2ValueChanged(app, event)
            app.VoxelHeight.Value = app.HeightEditField_2.Value;
            
        end

        % Button pushed function: CalculateConcentrationButton
        function CalculateConcentrationButtonPushed(app, event)
            if class(app.VoxelWidth) ~= "double" & class(app.VoxelHeight) ~= "double" & class(app.VoxelLength) ~= "double"
                [app.Concentration.Value, app.VolumeL.Value] = concentration_calc_given_dimensions(app.NumMolecules.Value, app.VoxelLength.Value, ...
                                             app.VoxelWidth.Value, app.VoxelHeight.Value, app.DeltaXVoxels.Value, ...
                                             app.DeltaYVoxels.Value, app.DeltaZVoxels.Value); 
                 app.MolarConcentrationEditField.Value = app.Concentration.Value; 
            elseif (app.CellularVolume.Value ~= 0)
                app.CellularVolumeUnit.Value = app.DropDown.Value; 
                volume_units = struct("nanoliters", 1e-9, "microliters", 1e-6, "milliliters", 1e-3, "liters", 1); 
                [app.Concentration.Value, app.VolumeL.Value] = concentration_calc_given_volume(app.NumMolecules.Value, ...
                                             app.CellularVolume.Value, volume_units.(app.CellularVolumeUnit.Value)); 
                app.MolarConcentrationEditField.Value = app.Concentration.Value;                         
            end

        end

        % Value changed function: ScalingFactorUsedEditField
        function ScalingFactorUsedEditFieldValueChanged(app, event)

            app.ScalingFactor.Value = app.ScalingFactorUsedEditField.Value;
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            [filepath, name, ~] = fileparts(app.FileName.Value); 
            filename_without_extension = fullfile(filepath, name); 
            write_output(filename_without_extension, app.xLocation, app.yLocation, app.zLocation, app.IntensityLocation, app.Scaled, app.NonZeroMolecules); 
            make_summary_file(filename_without_extension, app.NumSpots.Value, app.NumMolecules.Value, app.VolumeL.Value, app.Concentration.Value, app.ScalingFactor.Value)
            closereq
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.CellularVolumeUnit.Value = app.DropDown.Value;
            
        end

        % Value changed function: VolumeEditField
        function VolumeEditFieldValueChanged(app, event)
            app.CellularVolume.Value = app.VolumeEditField.Value;
            
        end

        % Selection changed function: NumberOfPeaksButtonGroup
        function NumberOfPeaksButtonGroupSelectionChanged(app, event)
            switch app.NumberOfPeaksButtonGroup.SelectedObject.Text
                case "One peak"
                    app.NumPeaks.Value = 1; 
                case "Two peaks"
                    app.NumPeaks.Value = 2; 
            end      
        end

        % Value changed function: X0_1EditField
        function X0_1EditFieldValueChanged(app, event)
            app.X01.Value = app.X0_1EditField.Value;
            
        end

        % Value changed function: X0_2EditField
        function X0_2EditFieldValueChanged(app, event)
            app.X02.Value = app.X0_2EditField.Value;
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThreeDAppUIFigure = uifigure('Visible', 'off');
            app.ThreeDAppUIFigure.Name = 'Main App';
            app.FileNameEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ThreeDAppUIFigure, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FindFileButton = uibutton(app.ThreeDAppUIFigure, 'push');
            app.FindFileButton.ButtonPushedFcn = createCallbackFcn(app, @FindFileButtonPushed, true);
            app.FindFileButton.Text = 'Find File';
            app.PlotButton = uibutton(app.ThreeDAppUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DoneButton = uibutton(app.ThreeDAppUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.BinWidthEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.BinWidthEditFieldLabel.Text = 'Bin Width';
            app.BinWidthEditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.BinWidthEditField.ValueChangedFcn = createCallbackFcn(app, @BinWidthEditFieldValueChanged, true);
            app.X0_1EditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.X0_1EditFieldLabel.Text = 'X0_1';
            app.X0_1EditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.X0_1EditField.ValueChangedFcn = createCallbackFcn(app, @X0_1EditFieldValueChanged, true);
            app.ScalingFactorUsedEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.ScalingFactorUsedEditFieldLabel.Text = 'Scaling Factor Used';
            app.ScalingFactorUsedEditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.ScalingFactorUsedEditField.ValueChangedFcn = createCallbackFcn(app, @ScalingFactorUsedEditFieldValueChanged, true);
            app.LengthEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.HeightEditField_2Label = uilabel(app.ThreeDAppUIFigure);
            app.HeightEditField_2Label.Text = 'Height';
            app.HeightEditField_2 = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.HeightEditField_2.ValueChangedFcn = createCallbackFcn(app, @HeightEditField_2ValueChanged, true);
            app.EnterpixelvoxeldimensionsinnanometersLabel = uilabel(app.ThreeDAppUIFigure);
            app.EnterpixelvoxeldimensionsinnanometersLabel.Text = 'Enter pixel/voxel dimensions in nanometers:';
            app.CalculateConcentrationButton = uibutton(app.ThreeDAppUIFigure, 'push');
            app.CalculateConcentrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateConcentrationButtonPushed, true);
            app.CalculateConcentrationButton.Text = 'Calculate Concentration';
            app.NumberOfMoleculesEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.NumberOfMoleculesEditFieldLabel.Text = 'Number Of Molecules';
            app.NumberOfMoleculesEditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.MolarConcentrationEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.MolarConcentrationEditFieldLabel.Text = 'Molar Concentration';
            app.MolarConcentrationEditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.Orfor3DimagesprovideoverallvolumeandunitsLabel = uilabel(app.ThreeDAppUIFigure);
            app.Orfor3DimagesprovideoverallvolumeandunitsLabel.Text = 'Or, for 3D images, provide overall volume and units: ';
            app.VolumeEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.VolumeEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.ThreeDAppUIFigure);
            app.DropDown.Items = {'nanoliters', 'microliters', 'milliliters', 'liters'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'nanoliters';
            app.X0_2EditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.X0_2EditFieldLabel.Text = 'X0_2';
            app.X0_2EditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            app.X0_2EditField.ValueChangedFcn = createCallbackFcn(app, @X0_2EditFieldValueChanged, true);
            app.NumberOfPeaksButtonGroup = uibuttongroup(app.ThreeDAppUIFigure);
            app.NumberOfPeaksButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NumberOfPeaksButtonGroupSelectionChanged, true);
            app.NumberOfPeaksButtonGroup.Title = 'Number Of Peaks';
            app.OnepeakButton = uiradiobutton(app.NumberOfPeaksButtonGroup);
            app.OnepeakButton.Text = 'One peak';
            app.OnepeakButton.Value = true;
            app.TwopeaksButton = uiradiobutton(app.NumberOfPeaksButtonGroup);
            app.TwopeaksButton.Text = 'Two peaks';
            app.For2DimagesprovideoverallareaandunitsLabel = uilabel(app.ThreeDAppUIFigure);
            app.For2DimagesprovideoverallareaandunitsLabel.Text = 'For 2D images, provide overall area and units: ';
            app.DropDown_2 = uidropdown(app.ThreeDAppUIFigure);
            app.DropDown_2.Items = {'nanoliters', 'microliters', 'milliliters', 'liters'};
            app.DropDown_2.Value = 'nanoliters';
            app.AreaEditFieldLabel = uilabel(app.ThreeDAppUIFigure);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.ThreeDAppUIFigure, 'numeric');
            % Show the figure after all components are created
            app.ThreeDAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InputParameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThreeDAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThreeDAppUIFigure)
        end
    end
end