classdef TwoD_Images < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TwoDAppUIFigure                 matlab.ui.Figure
        FindFileButton                  matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        PlotButton                      matlab.ui.control.Button
        BinWidthEditFieldLabel          matlab.ui.control.Label
        BinWidthEditField               matlab.ui.control.NumericEditField
        ChosenX0ValueEditFieldLabel     matlab.ui.control.Label
        ChosenX0ValueEditField          matlab.ui.control.NumericEditField
        ScalingFactorUsedEditFieldLabel  matlab.ui.control.Label
        ScalingFactorUsedEditField      matlab.ui.control.NumericEditField
        NumberOfMoleculesEditFieldLabel  matlab.ui.control.Label
        NumberOfMoleculesEditField      matlab.ui.control.NumericEditField
        MoleculesPerSpotEditFieldLabel  matlab.ui.control.Label
        MoleculesPerSpotEditField       matlab.ui.control.NumericEditField
        DoneButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        FileName
        HistogramPlot % Calls histogram plotting app 
        BinWidth
        ScalingFactor
        ChosenX0Value
        Scaled
        XData
        YData
        IntensityData
        DeltaXVoxels
        DeltaYVoxels
        BinsTruncated
        BinCountsTruncated
        NonZeroMolecules
        NumSpots
        NumMolecules
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TwoDAppUIFigure.Name = "Main App"; 
        end

        % Button pushed function: FindFileButton
        function FindFileButtonPushed(app, event)
            [file, path] = uigetfile('*.loc4', 'Select File'); 
            app.FileNameEditField.Value = fullfile(path, file); 
            app.FileName.Value = app.FileNameEditField.Value;
            
            app.ScalingFactor.Value = 150; 
            [scaled, x_data, y_data, intensity_data, scaling_factor_used, delta_x_voxels, ...
            delta_y_voxels] = ... 
            loc_file_reader2D(app.FileName.Value, 'default', app.ScalingFactor.Value);
            
            app.Scaled = scaled; 
            app.XData = x_data; 
            app.YData = y_data; 
            app.IntensityData = intensity_data; 
            app.ScalingFactor.Value = scaling_factor_used; 
            app.DeltaXVoxels.Value = delta_x_voxels; 
            app.DeltaYVoxels.Value = delta_y_voxels; 
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            [bins, bin_counts] = binning(app.Scaled, app.BinWidth.Value); 
            
            max_count = max(bin_counts); 
            
            app.BinsTruncated = [0, bins(bin_counts >= 0.15 * max_count)]; % added 0 because you need 1 more bin edge to enclose all bin_counts data
            app.BinCountsTruncated = bin_counts(bin_counts >= 0.15 * max_count); 
            
            h = findall(0, 'Type', 'figure'); 
            if length(h) >= 2
                for i = 1:length(h) 
                    h(i).Name
                    if ~strcmp(h(i).Name, app.TwoDAppUIFigure.Name)
                       close(h(i))
                    end
                end
            end
            
            app.HistogramPlot = HistogramPlot(app, app.BinWidth.Value, app.BinsTruncated, app.BinCountsTruncated); 
            
            % had to set this here instead of giving ChosenX0Value its own 
            % callback because I want the user to be able to choose the 
            % appropriate bar on the histogram and convert that to the
            % correct x0 value, but I couldn't figure out how to do that
            % before actually generating the histogram, which only comes
            % after the plot button is pushed
            app.ChosenX0Value.Value = app.BinsTruncated(app.ChosenX0ValueEditField.Value + 1);                                                                         
            [app.NonZeroMolecules, app.NumSpots.Value, app.NumMolecules.Value] = spot_quantification(app.ChosenX0Value.Value, app.Scaled); 
            app.NumberOfMoleculesEditField.Value = app.NumMolecules.Value; 
            
        end

        % Value changed function: BinWidthEditField
        function BinWidthEditFieldValueChanged(app, event)
            app.BinWidth.Value = app.BinWidthEditField.Value;
            
        end

        % Value changed function: ChosenX0ValueEditField
        function ChosenX0ValueEditFieldValueChanged(app, event)
            app.ChosenX0Value.Value = app.ChosenX0ValueEditField.Value;
            
        end

        % Value changed function: ScalingFactorUsedEditField
        function ScalingFactorUsedEditFieldValueChanged(app, event)
            app.ScalingFactor.Value = app.ScalingFactorUsedEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TwoDAppUIFigure = uifigure('Visible', 'off');
            app.TwoDAppUIFigure.Name = 'UI Figure';
            app.FindFileButton = uibutton(app.TwoDAppUIFigure, 'push');
            app.FindFileButton.ButtonPushedFcn = createCallbackFcn(app, @FindFileButtonPushed, true);
            app.FindFileButton.Text = 'Find File';
            app.FileNameEditFieldLabel = uilabel(app.TwoDAppUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.TwoDAppUIFigure, 'text');
            app.PlotButton = uibutton(app.TwoDAppUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.BinWidthEditFieldLabel = uilabel(app.TwoDAppUIFigure);
            app.BinWidthEditFieldLabel.Text = 'Bin Width';
            app.BinWidthEditField = uieditfield(app.TwoDAppUIFigure, 'numeric');
            app.BinWidthEditField.ValueChangedFcn = createCallbackFcn(app, @BinWidthEditFieldValueChanged, true);
            app.ChosenX0ValueEditFieldLabel = uilabel(app.TwoDAppUIFigure);
            app.ChosenX0ValueEditFieldLabel.Text = 'Chosen X0 Value';
            app.ChosenX0ValueEditField = uieditfield(app.TwoDAppUIFigure, 'numeric');
            app.ChosenX0ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ChosenX0ValueEditFieldValueChanged, true);
            app.ScalingFactorUsedEditFieldLabel = uilabel(app.TwoDAppUIFigure);
            app.ScalingFactorUsedEditFieldLabel.Text = 'Scaling Factor Used';
            app.ScalingFactorUsedEditField = uieditfield(app.TwoDAppUIFigure, 'numeric');
            app.ScalingFactorUsedEditField.ValueChangedFcn = createCallbackFcn(app, @ScalingFactorUsedEditFieldValueChanged, true);
            app.NumberOfMoleculesEditFieldLabel = uilabel(app.TwoDAppUIFigure);
            app.NumberOfMoleculesEditFieldLabel.Text = 'Number Of Molecules';
            app.NumberOfMoleculesEditField = uieditfield(app.TwoDAppUIFigure, 'numeric');
            app.MoleculesPerSpotEditFieldLabel = uilabel(app.TwoDAppUIFigure);
            app.MoleculesPerSpotEditFieldLabel.Text = 'Molecules Per Spot';
            app.MoleculesPerSpotEditField = uieditfield(app.TwoDAppUIFigure, 'numeric');
            app.DoneButton = uibutton(app.TwoDAppUIFigure, 'push');
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.TwoDAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoD_Images

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TwoDAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TwoDAppUIFigure)
        end
    end
end